classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CarregarNeuralNetworkMenu     matlab.ui.container.Menu
        GravarNeuralNetworkMenu       matlab.ui.container.Menu
        SimulaaocomCaraterunicoPanel  matlab.ui.container.Panel
        InputsPanel_3                 matlab.ui.container.Panel
        EscolherButton_3              matlab.ui.control.Button
        EditField_8                   matlab.ui.control.EditField
        FicheiroparaSimulaaoLabel     matlab.ui.control.Label
        FigurareconhecidaLabel        matlab.ui.control.Label
        SimularcomFicheiroButton      matlab.ui.control.Button
        SimularcomDesenhoButton       matlab.ui.control.Button
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        SimulaaocomDatasetPanel       matlab.ui.container.Panel
        SimularcomDatasetButton       matlab.ui.control.Button
        InputsPanel_2                 matlab.ui.container.Panel
        DropDown_2                    matlab.ui.control.DropDown
        OrdemdosFicheirosLabel_2      matlab.ui.control.Label
        EscolherButton_2              matlab.ui.control.Button
        EditField_7                   matlab.ui.control.EditField
        PastaparaSimulaaoLabel        matlab.ui.control.Label
        CriaaodeNeuralNetworkPanel    matlab.ui.container.Panel
        CriaretreinarRedeButton       matlab.ui.control.Button
        InputsPanel                   matlab.ui.container.Panel
        EditField_9                   matlab.ui.control.EditField
        DropDown_3                    matlab.ui.control.DropDown
        NomedaredeLabel               matlab.ui.control.Label
        EscolherButton                matlab.ui.control.Button
        PastaparaTreinoLabel          matlab.ui.control.Label
        ConfiguraaodaRedePanel        matlab.ui.container.Panel
        FunaodeAtivaao2DropDown       matlab.ui.control.DropDown
        FunaodeAtivaao2DropDownLabel  matlab.ui.control.Label
        FunaodeAtivaao1DropDown       matlab.ui.control.DropDown
        FunaodeAtivaao1DropDownLabel  matlab.ui.control.Label
        EditField_4                   matlab.ui.control.NumericEditField
        EditField_3                   matlab.ui.control.NumericEditField
        EditField_2                   matlab.ui.control.NumericEditField
        EditField                     matlab.ui.control.NumericEditField
        testLabel                     matlab.ui.control.Label
        valLabel                      matlab.ui.control.Label
        trainLabel                    matlab.ui.control.Label
        FunaodeDivisaoDropDown        matlab.ui.control.DropDown
        FunaodeDivisaoDropDownLabel   matlab.ui.control.Label
        TopologiaLabel                matlab.ui.control.Label
        FunaodeTreinoDropDown         matlab.ui.control.DropDown
        FunaodeTreinoDropDownLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            hFH = drawfreehand(app.UIAxes);
            clc;  % Clear command window
            close all;  % Close all figure windows except those created by imtool.
            imtool close all;  % Close all figure windows created by imtool.
            xy = hFH.Position;
            % Overlay what they drew onto the image.
            hold on; % Keep image, and direction of y axis.
            disp(xy);
            xCoordinates = xy(:, 1);
            yCoordinates = xy(:, 2);
            cla;
            hold off;
            for k = 1 : length(xCoordinates)
                row = int32(yCoordinates(k));
                column = int32(xCoordinates(k));
                fprintf("row: %d,  column: %d\n", row, column);
                grayImage(row, column) = 0;
            end
            imshow(grayImage);
            axis on;
            caption = sprintf('Grayscale Image with Burned In Curve.\nPoints may not lie on adjacent pixels, depends on your speed of drawing!', 'FontSize', fontSize);
            title(caption, 'FontSize', fontSize);


        end

        % Button pushed function: CriaretreinarRedeButton
        function CriaretreinarRedeButtonPushed(app, event)
          fprintf("%s", app.FunaodeTreinoDropDown.Value);
            
            trainNetwork(app.FunaodeTreinoDropDown.Value,app.FunaodeAtivaao1DropDown.Value,"tansig",app.FunaodeDivisaoDropDown.Value,app.EditField.Value,app.EditField_2.Value,app.EditField_3.Value,app.DropDown_3.Value,app.EditField_9.Value);
        end

        % Button pushed function: SimularcomDesenhoButton
        function SimularcomDesenhoButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CarregarNeuralNetworkMenu = uimenu(app.UIFigure);
            app.CarregarNeuralNetworkMenu.Text = 'Carregar Neural Network';
            app.GravarNeuralNetworkMenu = uimenu(app.UIFigure);
            app.GravarNeuralNetworkMenu.Text = 'Gravar Neural Network';
            app.CriaaodeNeuralNetworkPanel = uipanel(app.UIFigure);
            app.CriaaodeNeuralNetworkPanel.Title = 'Criaçao de Neural Network';
            app.ConfiguraaodaRedePanel = uipanel(app.CriaaodeNeuralNetworkPanel);
            app.ConfiguraaodaRedePanel.Title = 'Configuraçao da Rede';
            app.FunaodeTreinoDropDownLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.FunaodeTreinoDropDownLabel.Text = {'Funçao de Treino'; ''};
            app.FunaodeTreinoDropDown = uidropdown(app.ConfiguraaodaRedePanel);
            app.FunaodeTreinoDropDown.Items = {'trainlm', 'trainbfg', 'trainrp', 'trainscg', 'traincgb', 'traincgf', 'traincgp', 'trainoss', 'traingdx'};
            app.FunaodeTreinoDropDown.Value = 'trainlm';
            app.TopologiaLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.TopologiaLabel.Text = 'Topologia';
            app.FunaodeDivisaoDropDownLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.FunaodeDivisaoDropDownLabel.Text = 'Funçao de Divisao';
            app.FunaodeDivisaoDropDown = uidropdown(app.ConfiguraaodaRedePanel);
            app.FunaodeDivisaoDropDown.Items = {'dividerand', 'divideblock', 'divideint', 'divideind'};
            app.FunaodeDivisaoDropDown.Value = 'dividerand';
            app.trainLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.trainLabel.Text = 'train';
            app.valLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.valLabel.Text = 'val';
            app.testLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.testLabel.Text = 'test';
            app.EditField = uieditfield(app.ConfiguraaodaRedePanel, 'numeric');
            app.EditField.Value = 0.7;
            app.EditField_2 = uieditfield(app.ConfiguraaodaRedePanel, 'numeric');
            app.EditField_2.Value = 0.15;
            app.EditField_3 = uieditfield(app.ConfiguraaodaRedePanel, 'numeric');
            app.EditField_3.Value = 0.15;
            app.EditField_4 = uieditfield(app.ConfiguraaodaRedePanel, 'numeric');
            app.FunaodeAtivaao1DropDownLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.FunaodeAtivaao1DropDownLabel.Text = 'Funçao de Ativaçao 1';
            app.FunaodeAtivaao1DropDown = uidropdown(app.ConfiguraaodaRedePanel);
            app.FunaodeAtivaao1DropDown.Items = {'tansig', 'logsing', 'tansig', 'purelin'};
            app.FunaodeAtivaao1DropDown.Value = 'tansig';
            app.FunaodeAtivaao2DropDownLabel = uilabel(app.ConfiguraaodaRedePanel);
            app.FunaodeAtivaao2DropDownLabel.Text = 'Funçao de Ativaçao 2';
            app.FunaodeAtivaao2DropDown = uidropdown(app.ConfiguraaodaRedePanel);
            app.FunaodeAtivaao2DropDown.Items = {'tansig', 'logsing', 'tansig', 'purelin'};
            app.FunaodeAtivaao2DropDown.Value = 'tansig';
            app.InputsPanel = uipanel(app.CriaaodeNeuralNetworkPanel);
            app.InputsPanel.Title = 'Inputs';
            app.PastaparaTreinoLabel = uilabel(app.InputsPanel);
            app.PastaparaTreinoLabel.Text = 'Pasta para Treino';
            app.EscolherButton = uibutton(app.InputsPanel, 'push');
            app.EscolherButton.Text = 'Escolher';
            app.NomedaredeLabel = uilabel(app.InputsPanel);
            app.NomedaredeLabel.Text = 'Nome da rede';
            app.DropDown_3 = uidropdown(app.InputsPanel);
            app.DropDown_3.Items = {'start', 'test', 'train', ''};
            app.DropDown_3.Value = 'start';
            app.EditField_9 = uieditfield(app.InputsPanel, 'text');
            app.CriaretreinarRedeButton = uibutton(app.CriaaodeNeuralNetworkPanel, 'push');
            app.CriaretreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CriaretreinarRedeButtonPushed, true);
            app.CriaretreinarRedeButton.Text = 'Criar e treinar Rede';
            app.SimulaaocomDatasetPanel = uipanel(app.UIFigure);
            app.SimulaaocomDatasetPanel.Title = 'Simulaçao com Dataset';
            app.InputsPanel_2 = uipanel(app.SimulaaocomDatasetPanel);
            app.InputsPanel_2.Title = 'Inputs';
            app.PastaparaSimulaaoLabel = uilabel(app.InputsPanel_2);
            app.PastaparaSimulaaoLabel.Text = 'Pasta para Simulaçao';
            app.EditField_7 = uieditfield(app.InputsPanel_2, 'text');
            app.EscolherButton_2 = uibutton(app.InputsPanel_2, 'push');
            app.EscolherButton_2.Text = 'Escolher';
            app.OrdemdosFicheirosLabel_2 = uilabel(app.InputsPanel_2);
            app.OrdemdosFicheirosLabel_2.Text = 'Ordem dos Ficheiros';
            app.DropDown_2 = uidropdown(app.InputsPanel_2);
            app.DropDown_2.Items = {'Alfabetica (grupos de 10)', 'Alfabetica invertida (grupos de 10)'};
            app.DropDown_2.Value = 'Alfabetica (grupos de 10)';
            app.SimularcomDatasetButton = uibutton(app.SimulaaocomDatasetPanel, 'push');
            app.SimularcomDatasetButton.Text = 'Simular com Dataset';
            app.SimulaaocomCaraterunicoPanel = uipanel(app.UIFigure);
            app.SimulaaocomCaraterunicoPanel.Title = 'Simulaçao com Carater unico';
            app.UIAxes = uiaxes(app.SimulaaocomCaraterunicoPanel);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_3 = uiaxes(app.SimulaaocomCaraterunicoPanel);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.SimularcomDesenhoButton = uibutton(app.SimulaaocomCaraterunicoPanel, 'push');
            app.SimularcomDesenhoButton.ButtonPushedFcn = createCallbackFcn(app, @SimularcomDesenhoButtonPushed, true);
            app.SimularcomDesenhoButton.Text = 'Simular com Desenho';
            app.SimularcomFicheiroButton = uibutton(app.SimulaaocomCaraterunicoPanel, 'push');
            app.SimularcomFicheiroButton.Text = 'Simular com Ficheiro';
            app.FigurareconhecidaLabel = uilabel(app.SimulaaocomCaraterunicoPanel);
            app.FigurareconhecidaLabel.Text = 'Figura reconhecida:';
            app.InputsPanel_3 = uipanel(app.SimulaaocomCaraterunicoPanel);
            app.InputsPanel_3.Title = 'Inputs';
            app.FicheiroparaSimulaaoLabel = uilabel(app.InputsPanel_3);
            app.FicheiroparaSimulaaoLabel.Text = 'Ficheiro para Simulaçao';
            app.EditField_8 = uieditfield(app.InputsPanel_3, 'text');
            app.EscolherButton_3 = uibutton(app.InputsPanel_3, 'push');
            app.EscolherButton_3.Text = 'Escolher';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end